classdef test2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ImageAxes         matlab.ui.control.UIAxes
        LoadImageButton   matlab.ui.control.StateButton
        StartButton       matlab.ui.control.Button
        SaveImageButton   matlab.ui.control.Button
        PlotPointsButton  matlab.ui.control.StateButton
    end


    methods (Access = private)

        % Value changed function: LoadImageButton
        function load_image(app, event)
            % value = app.LoadImageButton.Value;
            [filename, pathname] = uigetfile( ...
                {'*.jpg', 'All jpg-Files (*.jpg)'; '*.bmp', 'All bmp-Files (*.bmp)'; '*.png', 'All png-Files (*.png)'; '*.*', 'All Files'} , 'Select Address Book');
            File = fullfile (pathname, filename);
            thisImage = imread(File);
            % thisImage = imread('front1.jpg');
            % imshow(thisImage);
            imshow(thisImage, 'Parent', app.ImageAxes);
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            %[filename, pathname] = uiputfile ( ...
            %    {'*.jpg', 'All jpg-Files (*.jpg)'; '*.bmp', 'All bmp-Files (*.bmp)'; '*.png', 'All png-Files (*.png)'; '*.*', 'All Files'} , 'Save File Name');
            % File = fullfile (pathname, filename);
            axesImage = findobj (app.ImageAxes, 'type', 'image');
            imsave (axesImage);
            % x = 0;
            % imh = findobj (app.ImageAxes, 'type', 'image');
            % saveas (imh, 'Parent', 'snaked.jpg');
            % saveas(gca, 'snaked.jpg');
            % x = x + 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Image')
            app.LoadImageButton = uibutton(app.UIFigure, 'state');
            app.LoadImageButton.ValueChangedFcn = createCallbackFcn(app, @load_image, true);
            app.LoadImageButton.Text = 'Load Image';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.Text = 'Start';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.PlotPointsButton = uibutton(app.UIFigure, 'state');
            app.PlotPointsButton.Text = 'Plot Points';
        end
    end

    methods (Access = public)

        % Construct app
        function app = test2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end