classdef test3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ImageAxes         matlab.ui.control.UIAxes
        LoadImageButton   matlab.ui.control.StateButton
        StartButton       matlab.ui.control.Button
        SaveImageButton   matlab.ui.control.Button
        PlotPointsButton  matlab.ui.control.StateButton
        TextArea          matlab.ui.control.TextArea
    end


    methods (Access = public)
    
        function getSnake(app)
            % image: The image on which snake is to be initialized
            % xs, xy: Initial position of the snake
            thisImage = findobj (app.ImageAxes, 'type', 'image');
            imagesc(handles.thisImage);    
            hold on; %Hold the image steady       
            x = [];
            y = [];
            xy = [];
            n = 0;
            [x, y] = ginput (100);
            n = n+1;
            for i = 0: length (n)
                offset = min (10, size (noise, 2) - n (i));
                xy(i, (end-offset): end) = noise (n(i): n(i)+offset);
            end
            % xy(:,n) = [xy(1,1);xy(2,1)];
            % Interpolate with a spline curve and finer spacing.
            t = 1:n;
            ts = 1: 0.1: n;
            xys = spline(t,xy,ts);
            xs = xys(1,:);
            ys = xys(2,:);
            
            [x, y] = getpts (gcf);
        end
        
    end


    methods (Access = private)

        % Value changed function: LoadImageButton
        function load_image(app, event)
            % value = app.LoadImageButton.Value;
            [filename, pathname] = uigetfile( ...
                {'*.jpg', 'All jpg-Files (*.jpg)'; '*.bmp', 'All bmp-Files (*.bmp)'; '*.png', 'All png-Files (*.png)'; '*.*', 'All Files'} , 'Select Address Book');
            File = fullfile (pathname, filename);
            thisImage = imread(File);
            % thisImage = imread('front1.jpg');
            % imshow(thisImage);
            imshow(thisImage, 'Parent', app.ImageAxes);
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            %[filename, pathname] = uiputfile ( ...
            %    {'*.jpg', 'All jpg-Files (*.jpg)'; '*.bmp', 'All bmp-Files (*.bmp)'; '*.png', 'All png-Files (*.png)'; '*.*', 'All Files'} , 'Save File Name');
            % File = fullfile (pathname, filename);
            axesImage = findobj (app.ImageAxes, 'type', 'image');
            imsave (axesImage);
            % x = 0;
            % imh = findobj (app.ImageAxes, 'type', 'image');
            % saveas (imh, 'Parent', 'snaked.jpg');
            % saveas(gca, 'snaked.jpg');
            % x = x + 1;
        end

        % Value changed function: PlotPointsButton
        function PlotPointsButtonValueChanged(app, event)
            disp('Instructions:');
            disp('Left click to mark points on the image');
            disp('There is a maximum of 2000 points');
            disp('Right click to finish plotting');
            disp('"Backspace"/"Delete" will remove the last point');
            disp('"Enter" will end plotting without a final point');
            getSnake(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Image')
            app.LoadImageButton = uibutton(app.UIFigure, 'state');
            app.LoadImageButton.ValueChangedFcn = createCallbackFcn(app, @load_image, true);
            app.LoadImageButton.Text = 'Load Image';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.Text = 'Start';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.PlotPointsButton = uibutton(app.UIFigure, 'state');
            app.PlotPointsButton.ValueChangedFcn = createCallbackFcn(app, @PlotPointsButtonValueChanged, true);
            app.PlotPointsButton.Text = 'Plot Points';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = test3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end